classdef ScanLineAppByAK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScanningPatternUIFigure     matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        UITable2                    matlab.ui.control.Table
        ResetDefaultsettingsButton  matlab.ui.control.Button
        LoopcontrolPanel            matlab.ui.container.Panel
        Switch                      matlab.ui.control.Switch
        StartLoopButton             matlab.ui.control.StateButton
        Label                       matlab.ui.control.Label
        LoopnoLabel                 matlab.ui.control.Label
        TotalLoopnoEditField        matlab.ui.control.NumericEditField
        TotalLoopnoLabel            matlab.ui.control.Label
        ReverseDirectionCheckBox    matlab.ui.control.CheckBox
        SwowScanPathButton          matlab.ui.control.Button
        AboutButton                 matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        MdAmanKhanRALabel           matlab.ui.control.Label
        SPMTampereUniversityLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        SerialNumberLabel           matlab.ui.control.Label
        ParametersLabel             matlab.ui.control.Label
        Valuesshouldbe01Accelaretion50001Velocity250point12Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        loop_break=false;
        loop_number=5;
        p;
        previous_px=0;
        previous_py=0;
        controller;
        x_axis_controller;
        y_axis_controller;
        figure;
        reverse_direction=0;
        extra_dialog_box;
        about_box;
    end
    
    methods (Access = private)
        
        function update(app)            
            newDisplayData = app.UITable2.DisplayData;
            app.controller= table2struct(newDisplayData);
            controller=app.controller;
            save('configaretion_serial_number.mat','controller')
            
            newDisplayData = app.UITable.DisplayData;
            app.p= table2struct(newDisplayData);
            p=app.p;
            save('configaretion_parameter.mat','p')          
            
        end
    end


    methods (Access = public)
        
        function Servo_interface(app)
            %initialization
            serial_number_1 =app.controller.serial_number_x_axis;
            serial_number_2 =app.controller.serial_number_y_axis;
            
            waitbar_h=waitbar(.1,'Please wait, Loading ActiveX Controller');


            f=.7; %megnification factor of the figure contenier
            fpos(1) = 100;
            fpos(2) = 100;
            fpos(3) = f*480; % window width
            fpos(4) = f*2*300; % window height
            app.figure = figure('Position', fpos,'Menu','None','Name','Controller Interface');
            
            %% activeX controller
            app.x_axis_controller = actxcontrol('MGMOTOR.MGMotorCtrl.1',[f*10 f*295 f*450 f*300 ], app.figure);
            app.y_axis_controller = actxcontrol('MGMOTOR.MGMotorCtrl.1',[f*10 f*1 f*450 f*300 ], app.figure);
            
            %% initialize and start control
            app.x_axis_controller.StartCtrl;
            app.y_axis_controller.StartCtrl;
            
            %% set the serial number
            waitbar(.2,waitbar_h,'Seting the serial number')
            set(app.x_axis_controller,'HWSerialNum', serial_number_1);
            pause(1)
            set(app.y_axis_controller,'HWSerialNum', serial_number_2);
            
            %% indentify the device serial number
            waitbar(.5,waitbar_h,'Indentifing the devices')
            app.x_axis_controller.Identify;
            pause(.5)
            app.y_axis_controller.Identify;
            pause(5); % waiting for the GUI load;
            
           
            %% Moving to home position
            waitbar(.7,waitbar_h,'Moving to home position')
            app.x_axis_controller.MoveHome(0,0);
            pause(1)
            app.y_axis_controller.MoveHome(0,0);
            waitbar(1,waitbar_h,'Done!')
            delete(waitbar_h);

            
        end
        
        function go_to_the_point(app,i)
            p=app.p;

            if isequal(p(i).x,app.previous_px)
                app.y_axis_controller.SetVelParams(0,0,p(i).acc,p(i).vel);
                app.y_axis_controller.SetAbsMovePos(0,p(i).y);
                app.y_axis_controller.MoveAbsolute(0,1==1);
            elseif isequal(p(i).y,app.previous_py)
                app.x_axis_controller.SetVelParams(0,0,p(i).acc,p(i).vel);
                app.x_axis_controller.SetAbsMovePos(0,p(i).x);
                app.x_axis_controller.MoveAbsolute(0,1==1);
            else
                if abs(app.previous_px-p(i).x) > abs(app.previous_py-p(i).y)

                
                    app.y_axis_controller.SetVelParams(0,0,p(i).acc,p(i).vel);
                    app.y_axis_controller.SetAbsMovePos(0,p(i).y);
                    app.y_axis_controller.MoveAbsolute(0,1==0);
                    
                    app.x_axis_controller.SetVelParams(0,0,p(i).acc,p(i).vel);
                    app.x_axis_controller.SetAbsMovePos(0,p(i).x);
                    app.x_axis_controller.MoveAbsolute(0,1==1);
                else                
                    app.x_axis_controller.SetVelParams(0,0,p(i).acc,p(i).vel);
                    app.x_axis_controller.SetAbsMovePos(0,p(i).x);
                    app.x_axis_controller.MoveAbsolute(0,1==0);
                    
                    app.y_axis_controller.SetVelParams(0,0,p(i).acc,p(i).vel);
                    app.y_axis_controller.SetAbsMovePos(0,p(i).y);
                    app.y_axis_controller.MoveAbsolute(0,1==1);
                end
            end                
            
            app.previous_px=p(i).x;
            app.previous_py=p(i).y;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                if exist('configaretion_parameter.mat', 'file')==2 && exist('configaretion_serial_number.mat', 'file')==2
                    q=load('configaretion_parameter.mat', 'p');
                    app.p=q.p;
                    c=load('configaretion_serial_number.mat','controller');
                    app.controller=c.controller;
                else
                    [app.p,app.controller]= default_variable();
                end
                
                Servo_interface(app);
                
                app.UITable2.Data =struct2table(app.controller);
                app.UITable.Data =struct2table(app.p);
                app.TotalLoopnoEditField.Enable=0;
                app.TotalLoopnoEditField.Visible=0;
                app.TotalLoopnoLabel.Visible=0;
                
            catch                
                uialert(uifigure,'Check USB Connection\ncheck the power\check the driver is installed properly','Error');
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            update(app)
        end

        % Display data changed function: UITable2
        function UITable2DisplayDataChanged(app, event)
            update(app)
        end

        % Button pushed function: ResetDefaultsettingsButton
        function ResetDefaultsettingsButtonPushed(app, event)
            [app.p,app.controller]= default_variable();           
            app.UITable2.Data =struct2table(app.controller);
            app.UITable.Data =struct2table(app.p);
            
            p=app.p;
            controller=app.controller;
            save('configaretion_serial_number.mat','controller')
            save('configaretion_parameter.mat','p') 
            
        end

        % Value changed function: StartLoopButton
        function StartLoopButtonValueChanged(app, event)
            update(app)
            i=1;
                        
            q=load('configaretion_parameter.mat', 'p');
            app.p=q.p;
                    
            
            while app.StartLoopButton.Value
                app.StartLoopButton.Text='Stop Loop';
                app.Label.Text= num2str(i);
                
                if app.reverse_direction==0
                    pause(.1)
                    go_to_the_point(app,1)
                    %second point
                    go_to_the_point(app,2)
                    %third point
                    go_to_the_point(app,3)
                    %forth point
                    go_to_the_point(app,4)
                end
                
                if app.reverse_direction==1
                    pause(.1)
                    go_to_the_point(app,4)
                    go_to_the_point(app,3)
                    go_to_the_point(app,2)
                    go_to_the_point(app,1)
                end
                
                if app.loop_number==i && app.loop_break
                    app.StartLoopButton.Value=false;
                end
                i=i+1;
            end
            if app.StartLoopButton.Value==false                
                app.StartLoopButton.Text='Start Loop';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp('Infinite loop',value)
                app.loop_break=false;
                app.TotalLoopnoEditField.Enable=0;
                app.TotalLoopnoEditField.Visible=0;
                app.TotalLoopnoLabel.Visible=0;
            end
            if strcmp('Finite loop',value)
                app.loop_break=true;
                app.TotalLoopnoLabel.Visible=1;
                app.TotalLoopnoEditField.Visible=1;
                app.TotalLoopnoEditField.Enable=1;
                app.TotalLoopnoEditField.Value=app.loop_number;                               
            end
            
        end

        % Value changed function: TotalLoopnoEditField
        function TotalLoopnoEditFieldValueChanged(app, event)
            value = app.TotalLoopnoEditField.Value;
            app.loop_number=value;
        end

        % Value changed function: ReverseDirectionCheckBox
        function ReverseDirectionCheckBoxValueChanged(app, event)
            value = app.ReverseDirectionCheckBox.Value;
            app.reverse_direction=value;
        end

        % Button pushed function: SwowScanPathButton
        function SwowScanPathButtonPushed(app, event)
            app.extra_dialog_box=extra(app);
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            app.about_box=about_box();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.extra_dialog_box)
            delete(app.about_box)
            delete(app.figure)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScanningPatternUIFigure = uifigure('Visible', 'off');
            app.ScanningPatternUIFigure.Name = 'Scanning Pattern';
            app.UITable = uitable(app.ScanningPatternUIFigure);
            app.UITable.ColumnName = {'Accelaretion'; 'Velocity'; 'x piont'; 'y point'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable2 = uitable(app.ScanningPatternUIFigure);
            app.UITable2.ColumnName = {'X axis Controller'; 'Y axis Controller'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2DisplayDataChanged, true);
            app.ResetDefaultsettingsButton = uibutton(app.ScanningPatternUIFigure, 'push');
            app.ResetDefaultsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultsettingsButtonPushed, true);
            app.ResetDefaultsettingsButton.Text = 'Reset Default settings';
            app.LoopcontrolPanel = uipanel(app.ScanningPatternUIFigure);
            app.LoopcontrolPanel.Title = 'Loop control';
            app.Switch = uiswitch(app.LoopcontrolPanel, 'slider');
            app.Switch.Items = {'Finite loop', 'Infinite loop'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Infinite loop';
            app.StartLoopButton = uibutton(app.LoopcontrolPanel, 'state');
            app.StartLoopButton.ValueChangedFcn = createCallbackFcn(app, @StartLoopButtonValueChanged, true);
            app.StartLoopButton.Text = 'Start Loop';
            app.Label = uilabel(app.LoopcontrolPanel);
            app.Label.Text = '0';
            app.LoopnoLabel = uilabel(app.LoopcontrolPanel);
            app.LoopnoLabel.Text = 'Loop no:';
            app.TotalLoopnoEditField = uieditfield(app.LoopcontrolPanel, 'numeric');
            app.TotalLoopnoEditField.ValueChangedFcn = createCallbackFcn(app, @TotalLoopnoEditFieldValueChanged, true);
            app.TotalLoopnoLabel = uilabel(app.LoopcontrolPanel);
            app.TotalLoopnoLabel.Text = 'Total Loop no. ';
            app.ReverseDirectionCheckBox = uicheckbox(app.LoopcontrolPanel);
            app.ReverseDirectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseDirectionCheckBoxValueChanged, true);
            app.ReverseDirectionCheckBox.Text = 'Reverse Direction';
            app.SwowScanPathButton = uibutton(app.ScanningPatternUIFigure, 'push');
            app.SwowScanPathButton.ButtonPushedFcn = createCallbackFcn(app, @SwowScanPathButtonPushed, true);
            app.SwowScanPathButton.Text = 'Swow Scan Path';
            app.AboutButton = uibutton(app.ScanningPatternUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.CloseButton = uibutton(app.ScanningPatternUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.MdAmanKhanRALabel = uilabel(app.ScanningPatternUIFigure);
            app.MdAmanKhanRALabel.Text = 'Md Aman Khan, RA ';
            app.SPMTampereUniversityLabel = uilabel(app.ScanningPatternUIFigure);
            app.SPMTampereUniversityLabel.Text = 'SPM, Tampere University';
            app.Image = uiimage(app.ScanningPatternUIFigure);
            app.Image.ImageSource = 'logo.png';
            app.SerialNumberLabel = uilabel(app.ScanningPatternUIFigure);
            app.SerialNumberLabel.Text = 'Serial Number';
            app.ParametersLabel = uilabel(app.ScanningPatternUIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.Valuesshouldbe01Accelaretion50001Velocity250point12Label = uilabel(app.ScanningPatternUIFigure);
            app.Valuesshouldbe01Accelaretion50001Velocity250point12Label.Text = '*Values should be: .01<=Accelaretion<=5.00; .01<=Velocity<=2.5; 0<=point<=12 ';
            % Show the figure after all components are created
            app.ScanningPatternUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScanLineAppByAK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScanningPatternUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScanningPatternUIFigure)
        end
    end
end